classdef AOMontagingGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        CloseMenu                     matlab.ui.container.Menu
        PreferencesMenu               matlab.ui.container.Menu
        InputSettingsMenu             matlab.ui.container.Menu
        OutputSettingsMenu            matlab.ui.container.Menu
        DeviceMenu                    matlab.ui.container.Menu
        MultimodalMenu                matlab.ui.container.Menu
        CanonMenu                     matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        SingleTimepointTab            matlab.ui.container.Tab
        ImageFolderButton             matlab.ui.control.Button
        ScalingPositionFileButton     matlab.ui.control.Button
        OutputFolderButton            matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        EditField_2                   matlab.ui.control.EditField
        EditField_3                   matlab.ui.control.EditField
        MontageTypeDropDownLabel      matlab.ui.control.Label
        MontageTypeDropDown           matlab.ui.control.DropDown
        TransformationTypeDropDownLabel  matlab.ui.control.Label
        TransformationTypeDropDown    matlab.ui.control.DropDown
        FeatureTypeDropDownLabel      matlab.ui.control.Label
        FeatureTypeDropDown           matlab.ui.control.DropDown
        LongitudinalTab               matlab.ui.container.Tab
        ImageFolderButton_2           matlab.ui.control.Button
        ScalingPositionFileButton_2   matlab.ui.control.Button
        EditField_4                   matlab.ui.control.EditField
        EditField_5                   matlab.ui.control.EditField
        BaselineLabel                 matlab.ui.control.Label
        ImageFolderButton_3           matlab.ui.control.Button
        ScalingPositionFileButton_3   matlab.ui.control.Button
        OutputFolderButton_3          matlab.ui.control.Button
        EditField_7                   matlab.ui.control.EditField
        EditField_8                   matlab.ui.control.EditField
        EditField_9                   matlab.ui.control.EditField
        FollowupLabel                 matlab.ui.control.Label
        ExistingMontageButton         matlab.ui.control.Button
        EditField_10                  matlab.ui.control.EditField
        TransformationTypeDropDown_2Label  matlab.ui.control.Label
        TransformationTypeDropDown_2  matlab.ui.control.DropDown
        FeatureTypeDropDown_2Label    matlab.ui.control.Label
        FeatureTypeDropDown_2         matlab.ui.control.DropDown
        CheckBox                      matlab.ui.control.CheckBox
        UseExistingMontageLabel       matlab.ui.control.Label
        TransformationTypeDropDown_3Label  matlab.ui.control.Label
        TransformationTypeDropDown_3  matlab.ui.control.DropDown
        FeatureTypeDropDown_3Label    matlab.ui.control.Label
        FeatureTypeDropDown_3         matlab.ui.control.DropDown
        MontageButton                 matlab.ui.control.Button
        SelectingDataLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        ImageFolder % Folder With All Input imagesDescription
        ImagesNames % List of all Images in Image Folder
        DeviceMode % Type of AO Device
        ModalitiesInfo %
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImageFolderButton
        function ImageFolderButtonPushed(app, event)
            selectedDir=uigetdir(app.ImageFolder);
            drawnow;
            figure(app.UIFigure)
            if(selectedDir == 0)
                return
            end
            app.ImageFolder = selectedDir;
            app.EditField.Value =  selectedDir;
            
            Allfiles = dir(fullfile(app.ImageFolder,'*.tif'));
            Allfiles = {Allfiles.name};
            
            %Use current identifiers to locate all images
            dataSummary = cell(1,1);
            dataSummary{1} = 'Input Data Summary:';
            
            app.ModalitiesInfo = {'Confocal' 'confocal';
                          'Split Detection' 'split_det';
                          'Dark Field' 'avg'};
            
           % if strcmp(handles.device_mode, 'multi_modal')
                for m = 1:size(app.ModalitiesInfo,1)
                    if (~isempty(app.ModalitiesInfo{m,2}))%check it's not empty
                        found = sort(Allfiles(~cellfun(@isempty, strfind(Allfiles, app.ModalitiesInfo{m,2}))));
                        app.ImagesNames = [app.ImagesNames, found];%search
                        dataSummary{end+1} =[num2str(size(found,2)),' ',char(app.ModalitiesInfo{m,1}),' image(s) found.'];
                    end
                end
                
                dataSummary{end+1} = '';
                dataSummary{end+1} = 'Note: You can modify how to search for each modality';
                dataSummary{end+1} = 'under Preferences->Input Settings.';
                
%             elseif strcmp(handles.device_mode, 'canon')
%                 found = sort(Allfiles(cellfun(@(s) strcmp(s(1:4),'206-'), Allfiles )));
%                 handles.imageFile_names = [handles.imageFile_names, found];%search
%                 dataSummary{end+1} =[num2str(size(found,2)),' image(s) found.'];
%             end
%             
            
            app.SelectingDataLabel.Text = dataSummary;
        end

        % Button pushed function: ScalingPositionFileButton
        function ScalingPositionFileButtonPushed(app, event)
            
        end

        % Button pushed function: OutputFolderButton
        function OutputFolderButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.Text = 'Close';
            app.PreferencesMenu = uimenu(app.UIFigure);
            app.PreferencesMenu.Text = 'Preferences';
            app.InputSettingsMenu = uimenu(app.PreferencesMenu);
            app.InputSettingsMenu.Text = 'Input Settings';
            app.OutputSettingsMenu = uimenu(app.PreferencesMenu);
            app.OutputSettingsMenu.Text = 'Output Settings';
            app.DeviceMenu = uimenu(app.PreferencesMenu);
            app.DeviceMenu.Text = 'Device';
            app.MultimodalMenu = uimenu(app.DeviceMenu);
            app.MultimodalMenu.Text = 'Multi-modal';
            app.CanonMenu = uimenu(app.DeviceMenu);
            app.CanonMenu.Text = 'Canon';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleTimepointTab = uitab(app.TabGroup);
            app.SingleTimepointTab.Title = 'Single Timepoint';
            app.ImageFolderButton = uibutton(app.SingleTimepointTab, 'push');
            app.ImageFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ImageFolderButtonPushed, true);
            app.ImageFolderButton.Text = 'Image Folder';
            app.ScalingPositionFileButton = uibutton(app.SingleTimepointTab, 'push');
            app.ScalingPositionFileButton.ButtonPushedFcn = createCallbackFcn(app, @ScalingPositionFileButtonPushed, true);
            app.ScalingPositionFileButton.Text = 'Scaling/Position File';
            app.OutputFolderButton = uibutton(app.SingleTimepointTab, 'push');
            app.OutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputFolderButtonPushed, true);
            app.OutputFolderButton.Text = {'Output Folder'; ''};
            app.EditField = uieditfield(app.SingleTimepointTab, 'text');
            app.EditField_2 = uieditfield(app.SingleTimepointTab, 'text');
            app.EditField_3 = uieditfield(app.SingleTimepointTab, 'text');
            app.MontageTypeDropDownLabel = uilabel(app.SingleTimepointTab);
            app.MontageTypeDropDownLabel.Text = 'Montage Type';
            app.MontageTypeDropDown = uidropdown(app.SingleTimepointTab);
            app.MontageTypeDropDown.Items = {'New', 'Append', ''};
            app.MontageTypeDropDown.Value = 'New';
            app.TransformationTypeDropDownLabel = uilabel(app.SingleTimepointTab);
            app.TransformationTypeDropDownLabel.Text = 'Transformation Type';
            app.TransformationTypeDropDown = uidropdown(app.SingleTimepointTab);
            app.TransformationTypeDropDown.Items = {'Rigid', 'Translation'};
            app.TransformationTypeDropDown.Value = 'Rigid';
            app.FeatureTypeDropDownLabel = uilabel(app.SingleTimepointTab);
            app.FeatureTypeDropDownLabel.Text = 'Feature Type';
            app.FeatureTypeDropDown = uidropdown(app.SingleTimepointTab);
            app.FeatureTypeDropDown.Items = {'SIFT', 'Constellation'};
            app.FeatureTypeDropDown.Value = 'SIFT';
            app.LongitudinalTab = uitab(app.TabGroup);
            app.LongitudinalTab.Title = 'Longitudinal';
            app.ImageFolderButton_2 = uibutton(app.LongitudinalTab, 'push');
            app.ImageFolderButton_2.Text = 'Image Folder';
            app.ScalingPositionFileButton_2 = uibutton(app.LongitudinalTab, 'push');
            app.ScalingPositionFileButton_2.Text = 'Scaling/Position File';
            app.EditField_4 = uieditfield(app.LongitudinalTab, 'text');
            app.EditField_5 = uieditfield(app.LongitudinalTab, 'text');
            app.BaselineLabel = uilabel(app.LongitudinalTab);
            app.BaselineLabel.Text = 'Baseline';
            app.ImageFolderButton_3 = uibutton(app.LongitudinalTab, 'push');
            app.ImageFolderButton_3.Text = 'Image Folder';
            app.ScalingPositionFileButton_3 = uibutton(app.LongitudinalTab, 'push');
            app.ScalingPositionFileButton_3.Text = 'Scaling/Position File';
            app.OutputFolderButton_3 = uibutton(app.LongitudinalTab, 'push');
            app.OutputFolderButton_3.Text = {'Output Folder'; ''};
            app.EditField_7 = uieditfield(app.LongitudinalTab, 'text');
            app.EditField_8 = uieditfield(app.LongitudinalTab, 'text');
            app.EditField_9 = uieditfield(app.LongitudinalTab, 'text');
            app.FollowupLabel = uilabel(app.LongitudinalTab);
            app.FollowupLabel.Text = 'Followup';
            app.ExistingMontageButton = uibutton(app.LongitudinalTab, 'push');
            app.ExistingMontageButton.Enable = 'off';
            app.ExistingMontageButton.Text = 'Existing Montage';
            app.EditField_10 = uieditfield(app.LongitudinalTab, 'text');
            app.TransformationTypeDropDown_2Label = uilabel(app.LongitudinalTab);
            app.TransformationTypeDropDown_2Label.Text = 'Transformation Type';
            app.TransformationTypeDropDown_2 = uidropdown(app.LongitudinalTab);
            app.TransformationTypeDropDown_2.Items = {'Rigid', 'Translation'};
            app.TransformationTypeDropDown_2.Value = 'Rigid';
            app.FeatureTypeDropDown_2Label = uilabel(app.LongitudinalTab);
            app.FeatureTypeDropDown_2Label.Text = 'Feature Type';
            app.FeatureTypeDropDown_2 = uidropdown(app.LongitudinalTab);
            app.FeatureTypeDropDown_2.Items = {'SIFT', 'Constellation'};
            app.FeatureTypeDropDown_2.Value = 'SIFT';
            app.CheckBox = uicheckbox(app.LongitudinalTab);
            app.CheckBox.Text = '';
            app.UseExistingMontageLabel = uilabel(app.LongitudinalTab);
            app.UseExistingMontageLabel.Text = 'Use Existing Montage';
            app.TransformationTypeDropDown_3Label = uilabel(app.LongitudinalTab);
            app.TransformationTypeDropDown_3Label.Text = 'Transformation Type';
            app.TransformationTypeDropDown_3 = uidropdown(app.LongitudinalTab);
            app.TransformationTypeDropDown_3.Items = {'Scale', 'Rigid', 'Translation'};
            app.TransformationTypeDropDown_3.Value = 'Scale';
            app.FeatureTypeDropDown_3Label = uilabel(app.LongitudinalTab);
            app.FeatureTypeDropDown_3Label.Text = 'Feature Type';
            app.FeatureTypeDropDown_3 = uidropdown(app.LongitudinalTab);
            app.FeatureTypeDropDown_3.Items = {'SIFT', 'Constellation'};
            app.FeatureTypeDropDown_3.Value = 'Constellation';
            app.MontageButton = uibutton(app.UIFigure, 'push');
            app.MontageButton.Text = 'Montage';
            app.SelectingDataLabel = uilabel(app.UIFigure);
            app.SelectingDataLabel.Text = {'Instructions:'; '1.) Select Input Data Folder'; '2.) Select Scale/Position File'; '3.) Select Output Folder'; '4.) Push "Montage"'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AOMontagingGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end